classdef RealGasVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DataTab                       matlab.ui.container.Tab
        rEditField                    matlab.ui.control.NumericEditField
        rEditField_2Label             matlab.ui.control.Label
        nEditField                    matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        AddRemoveDropDown             matlab.ui.control.DropDown
        CompoundDropDown              matlab.ui.control.DropDown
        NameEditField                 matlab.ui.control.EditField
        aEditField                    matlab.ui.control.NumericEditField
        bEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        aEditField_2Label             matlab.ui.control.Label
        ApplyButton                   matlab.ui.control.Button
        NameEditFieldLabel            matlab.ui.control.Label
        ViewTab                       matlab.ui.container.Tab
        MaxPressureEditField          matlab.ui.control.NumericEditField
        MaxPressureEditFieldLabel     matlab.ui.control.Label
        MaxTemperatureEditField       matlab.ui.control.NumericEditField
        MaxTemperatureEditFieldLabel  matlab.ui.control.Label
        ShowDropDown                  matlab.ui.control.DropDown
        ShowDropDownLabel             matlab.ui.control.Label
        SliceTab                      matlab.ui.container.Tab
        ViewSliceButton               matlab.ui.control.StateButton
        AxisDropDown                  matlab.ui.control.DropDown
        AxisDropDownLabel             matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        SliceSliderLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        current_data % Current data in use
        vdw_data % All available vdw constant data
        slice_val;

        pressure_range;
        temperature_range;

        default_compression_factor_lim = [0 5];
        default_volume_lim = [0 25];
    end

    methods (Access = private)
        
        function updateView(app)
            app.pressure_range
            app.temperature_range

            delete(app.UIAxes.Children)
            hold(app.UIAxes, "on")
            slicemode = app.ViewSliceButton.Value;
            
            if(slicemode)
                xlabel(app.UIAxes, app.AxisDropDown.Value);
                ylabel(app.UIAxes, app.ShowDropDown.Value);
                if app.AxisDropDown.Value == "Pressure"
                    units = "°K";
                else
                    units = "atm";
                end
                title(app.UIAxes, app.ShowDropDown.Value + " vs " + app.AxisDropDown.Value + " at " + app.slice_val + " " + units);
                app.viewSlice();
            else
                xlabel(app.UIAxes, "Pressure");
                ylabel(app.UIAxes, "Temperature");
                zlabel(app.UIAxes, app.ShowDropDown.Value)
                title(app.UIAxes, app.ShowDropDown.Value);
                app.view3d();
                if(app.AxisDropDown.Value ~= "None")
                    app.drawSlice();
                end
            end

            legend(app.UIAxes, app.current_data.Compound);
        end

        function view3d(app)
            for i=1:height(app.current_data)
                a = app.current_data.a(i);
                b = app.current_data.b(i);
                
                switch app.ShowDropDown.Value
                    case "Compression Factor"
                        plottable = @(p, t) compressionFactor(p, ...
                            realGasVolume(t, p, a, ...
                            b, app.nEditField.Value, app.rEditField.Value),...
                            app.nEditField.Value, app.rEditField.Value, t);
                    case "Volume"
                        plottable = @(p, t) realGasVolume(t, p, ...
                            a, b, app.nEditField.Value, app.rEditField.Value);
                end

                ezsurf(app.UIAxes, plottable, [app.pressure_range app.temperature_range]);
            end
        end

        function viewSlice(app)
%             switch(app.AxisDropDown.Value)

            for i=1:height(app.current_data)
                a = app.current_data.a(i);
                b = app.current_data.b(i);
                
                % Creates plottable function based on whats being sliced 
                % (pres or temp) and whats being shown (vol or comp fac)
                switch app.AxisDropDown.Value
                    case "Pressure"
                        t = app.slice_val;
                        switch app.ShowDropDown.Value
                            case "Volume"
                                func = @(p) realGasVolume(t, p, a, ...
                                    b, app.nEditField.Value, app.rEditField.Value);
                            case "Compression Factor"
                                func = @(p) compressionFactor(p, ...
                                    realGasVolume(t, p, a, ...
                                    b, app.nEditField.Value, app.rEditField.Value),...
                                    app.nEditField.Value, app.rEditField.Value, t);
                        end
                        range = app.pressure_range;
                    case "Temperature"
                        p = app.slice_val;
                        switch app.ShowDropDown.Value
                            case "Volume"
                                func = @(t) realGasVolume(t, p, a, ...
                                    b, app.nEditField.Value, app.rEditField.Value);
                            case "Compression Factor"
                                func = @(t) compressionFactor(p, ...
                                    realGasVolume(t, p, a, ...
                                    b, app.nEditField.Value, app.rEditField.Value),...
                                    app.nEditField.Value, app.rEditField.Value, t);
                        end
                        range = app.temperature_range;
                end
                % Plot the function over the specified range
                fplot(app.UIAxes, func, range);
            end
        end

        function drawSlice(app)
            z = [app.UIAxes.ZLim(1) app.UIAxes.ZLim(2) app.UIAxes.ZLim(2) app.UIAxes.ZLim(1)];
            switch(app.AxisDropDown.Value)
                case "Pressure"
                    y = app.slice_val * ones(1,4);
                    x = [app.UIAxes.XLim(1) app.UIAxes.XLim(1) app.UIAxes.XLim(2) app.UIAxes.XLim(2)];
                case "Temperature"
                    x = app.slice_val * ones(1,4);
                    y = [app.UIAxes.YLim(1) app.UIAxes.YLim(1) app.UIAxes.YLim(2) app.UIAxes.YLim(2)];
            end
            patch(app.UIAxes, x, y, z, 'r');
        end
        
        function UpdateCompoundDropdownOptions(app)
            if app.AddRemoveDropDown.Value == "Add"
                if isempty(app.current_data.Compound)
                    app.CompoundDropDown.Items = ["Custom"; app.vdw_data.Compound];
                else
                    app.CompoundDropDown.Items = ["Custom"; app.vdw_data.Compound(~ismember(app.vdw_data.Compound, app.current_data.Compound))];
                end
            elseif app.AddRemoveDropDown.Value == "Remove"
                if isempty(app.current_data.Compound)
                    app.CompoundDropDown.Items = "";
                else
                    app.CompoundDropDown.Items = app.current_data.Compound;
                end
            end
        end
        
        function updateRanges(app)
            app.pressure_range = [0 app.MaxPressureEditField.Value];
            app.temperature_range = [0 app.MaxTemperatureEditField.Value];

            % If slicing is happening, make sure slider limits are right.
            switch app.AxisDropDown.Value
                case "Pressure"
                    app.SliceSlider.Limits = app.temperature_range;
                case "Temperature"
                    app.SliceSlider.Limits = app.pressure_range;
            end
        end
        
        function setDefaultLims(app)
            
            if app.ViewSliceButton.Value
                switch app.AxisDropDown.Value
                    case "Pressure"
                        xlim(app.UIAxes, app.pressure_range);
                    case "Temperature"
                        xlim(app.UIAxes, app.temperature_range);
                end
                switch app.ShowDropDown.Value
                    case "Volume"
                        ylim(app.UIAxes, app.default_volume_lim)
                    case "Compression Factor"
                        ylim(app.UIAxes, app.default_compression_factor_lim);
                end
            else
                xlim(app.UIAxes, app.pressure_range);
                ylim(app.UIAxes, app.temperature_range);
                zlim(app.UIAxes, "auto");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Import Data
            app.vdw_data = load("vdw_constants.mat").VanDerWaalsConstants;
            app.current_data = cell2table(cell(0,3), 'VariableNames', {'Compound', 'a', 'b'});

            % Data tab
            app.UpdateCompoundDropdownOptions;

            % Slice tab
            app.slice_val = app.SliceSlider.Value;
            app.ViewSliceButton.Enable = "off";

            app.updateRanges();
            app.setDefaultLims();
        end

        % Value changed function: ViewSliceButton
        function SliceStateChange(app, event)
            if app.ViewSliceButton.Value
                view(app.UIAxes, 2);
            else
                view(app.UIAxes, 3);
            end
            app.updateView();
            app.setDefaultLims();
        end

        % Value changed function: CompoundDropDown
        function UpdateCompoundValues(app, event)
            value = app.CompoundDropDown.Value;
            if(value == "Custom")
                app.NameEditField.Enable = "on";
                app.NameEditField.Value = "";
                app.aEditField.Enable = "on";
                app.aEditField.Value = 0;
                app.bEditField.Enable = "on";
                app.bEditField.Value = 0;
            else
                app.NameEditField.Enable = "off";
                app.aEditField.Enable = "off";
                app.bEditField.Enable = "off";

                dataind = app.vdw_data.Compound == value;
                if ~any(dataind)
                    return;
                end
                
                
                app.NameEditField.Value = value;
                app.aEditField.Value = app.vdw_data.a(dataind);
                app.bEditField.Value = app.vdw_data.b(dataind);
            end
        end

        % Value changed function: AddRemoveDropDown
        function AddRemoveDropDownValueChanged(app, event)
            app.UpdateCompoundDropdownOptions();
            app.UpdateCompoundValues();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.AddRemoveDropDown.Value == "Remove"
                ind = contains(app.current_data.Compound, app.NameEditField.Value);
                app.current_data(ind, :) = [];
            elseif app.AddRemoveDropDown.Value == "Add"
                if isempty(app.NameEditField.Value)
                    return;
                end
                app.current_data = [app.current_data; cell2table({app.NameEditField.Value, ...
                    app.aEditField.Value, app.bEditField.Value}, 'VariableNames', {'Compound', 'a', 'b'})];
            end

            app.UpdateCompoundDropdownOptions();
            app.UpdateCompoundValues();
            app.updateView();
            app.setDefaultLims();

            % If the graph just changed from 2d to 3d, fix the camera
            if height(app.current_data) == 1 && ~app.ViewSliceButton.Value
                view(app.UIAxes, 3);
            end
        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            app.updateView();

            % Fix camera
            axis(app.UIAxes, 'auto');
            app.setDefaultLims();
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            app.slice_val = event.Value;
            app.updateView();
            app.setDefaultLims();
        end

        % Value changed function: AxisDropDown
        function AxisDropDownValueChanged(app, event)
            % Set slice mode to false if axis is none
            switch event.Value
                case "None"
                    app.ViewSliceButton.Value = false;
                    app.ViewSliceButton.Enable = "off";
                otherwise
                    app.ViewSliceButton.Enable = "on";
            end
            app.updateRanges();
            app.updateView();
            app.setDefaultLims();
        end

        % Value changed function: MaxTemperatureEditField
        function MaxTemperatureEditFieldValueChanged(app, event)
            % Dont allow values below 1
            if event.Value < 1
                app.MaxTemperatureEditField.Value = event.PreviousValue;
                return;
            end
            app.updateRanges();
            app.updateView();
            app.setDefaultLims();
        end

        % Value changed function: MaxPressureEditField
        function MaxPressureEditFieldValueChanged(app, event)
            % Dont allow values below 1
            if event.Value < 1
                app.MaxPressureEditField.Value = event.PreviousValue;
                return;
            end
            app.updateRanges();
            app.updateView();
            app.setDefaultLims();
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            app.updateView();
        end

        % Value changed function: rEditField
        function rEditFieldValueChanged(app, event)
            app.updateView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Compression Factor')
            xlabel(app.UIAxes, 'Pressure')
            ylabel(app.UIAxes, 'Temperature')
            zlabel(app.UIAxes, 'Compression Factor')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.NameEditFieldLabel = uilabel(app.DataTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.ApplyButton = uibutton(app.DataTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.aEditField_2Label = uilabel(app.DataTab);
            app.aEditField_2Label.Text = 'a';
            app.bEditFieldLabel = uilabel(app.DataTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.DataTab, 'numeric');
            app.aEditField = uieditfield(app.DataTab, 'numeric');
            app.NameEditField = uieditfield(app.DataTab, 'text');
            app.CompoundDropDown = uidropdown(app.DataTab);
            app.CompoundDropDown.Items = {};
            app.CompoundDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateCompoundValues, true);
            app.CompoundDropDown.Value = {};
            app.AddRemoveDropDown = uidropdown(app.DataTab);
            app.AddRemoveDropDown.Items = {'Add', 'Remove'};
            app.AddRemoveDropDown.ValueChangedFcn = createCallbackFcn(app, @AddRemoveDropDownValueChanged, true);
            app.AddRemoveDropDown.Value = 'Add';
            app.nEditFieldLabel = uilabel(app.DataTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.DataTab, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Value = 1;
            app.rEditField_2Label = uilabel(app.DataTab);
            app.rEditField_2Label.Text = 'r';
            app.rEditField = uieditfield(app.DataTab, 'numeric');
            app.rEditField.ValueChangedFcn = createCallbackFcn(app, @rEditFieldValueChanged, true);
            app.rEditField.Value = 0.08314;
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.ShowDropDownLabel = uilabel(app.ViewTab);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.ViewTab);
            app.ShowDropDown.Items = {'Compression Factor', 'Volume'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'Compression Factor';
            app.MaxTemperatureEditFieldLabel = uilabel(app.ViewTab);
            app.MaxTemperatureEditFieldLabel.Text = 'Max Temperature';
            app.MaxTemperatureEditField = uieditfield(app.ViewTab, 'numeric');
            app.MaxTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTemperatureEditFieldValueChanged, true);
            app.MaxTemperatureEditField.Value = 500;
            app.MaxPressureEditFieldLabel = uilabel(app.ViewTab);
            app.MaxPressureEditFieldLabel.Text = 'Max Pressure';
            app.MaxPressureEditField = uieditfield(app.ViewTab, 'numeric');
            app.MaxPressureEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPressureEditFieldValueChanged, true);
            app.MaxPressureEditField.Value = 800;
            app.SliceTab = uitab(app.TabGroup);
            app.SliceTab.Title = 'Slice';
            app.SliceSliderLabel = uilabel(app.SliceTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.SliceTab);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.AxisDropDownLabel = uilabel(app.SliceTab);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.SliceTab);
            app.AxisDropDown.Items = {'None', 'Pressure', 'Temperature'};
            app.AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.AxisDropDown.Value = 'None';
            app.ViewSliceButton = uibutton(app.SliceTab, 'state');
            app.ViewSliceButton.ValueChangedFcn = createCallbackFcn(app, @SliceStateChange, true);
            app.ViewSliceButton.Text = 'View Slice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RealGasVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end